classdef Vista < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProyectoProcesamientodeImgenesDigitalesUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup_ImgOriginal           matlab.ui.container.TabGroup
        Tab_ImgOriginal8               matlab.ui.container.Tab
        Grid_ImgOriginal8              matlab.ui.container.GridLayout
        Button_AbrirImg5               matlab.ui.control.Button
        Button_AbrirImg6               matlab.ui.control.Button
        Button_AbrirImg7               matlab.ui.control.Button
        Button_AbrirImg8               matlab.ui.control.Button
        Button_QuitarImg5              matlab.ui.control.Button
        Button_QuitarImg6              matlab.ui.control.Button
        Button_QuitarImg7              matlab.ui.control.Button
        Button_QuitarImg8              matlab.ui.control.Button
        UIAxes_ImgOriginal5            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal6            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal7            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal8            matlab.ui.control.UIAxes
        Tab_ImgOriginal4               matlab.ui.container.Tab
        Grid_ImgOriginal4              matlab.ui.container.GridLayout
        Button_AbrirImg2               matlab.ui.control.Button
        Button_AbrirImg3               matlab.ui.control.Button
        Button_AbrirImg4               matlab.ui.control.Button
        Button_QuitarImg2              matlab.ui.control.Button
        Button_QuitarImg3              matlab.ui.control.Button
        Button_QuitarImg4              matlab.ui.control.Button
        UIAxes_ImgOriginal1            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal2            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal3            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal4            matlab.ui.control.UIAxes
        Tab_ImgOriginal1               matlab.ui.container.Tab
        Grid_ImgOriginal               matlab.ui.container.GridLayout
        Panel_Archivo                  matlab.ui.container.Panel
        Grid_Archivo                   matlab.ui.container.GridLayout
        Button_AbrirImg1               matlab.ui.control.Button
        Button_QuitarImg1              matlab.ui.control.Button
        UIAxes_ImgOriginal             matlab.ui.control.UIAxes
        Panel_ImgProcesada             matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        Panel_ArchivoProcesada         matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        Button_Guardar                 matlab.ui.control.Button
        Button_Deshacer                matlab.ui.control.Button
        Panel_ConversionGrayscle       matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Button_Convertir               matlab.ui.control.Button
        UIAxes_ImgProcesada            matlab.ui.control.UIAxes
        MenuTabGroup                   matlab.ui.container.TabGroup
        Tab_OperacionesBasicas         matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        AdicinysustraccinPanel         matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        Requieredeimagen1imagen2Label  matlab.ui.control.Label
        SustraccinButton               matlab.ui.control.Button
        AdicinButton                   matlab.ui.control.Button
        InversinPanel                  matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        InvertirfotogrficaButton       matlab.ui.control.Button
        InvertirbinariaButton          matlab.ui.control.Button
        EcualizacinPanel               matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        MostrarhistogramasButton       matlab.ui.control.Button
        EcualizarButton                matlab.ui.control.Button
        Tab_Collage                    matlab.ui.container.Tab
        GridLayout21                   matlab.ui.container.GridLayout
        ConfiguracinPanel              matlab.ui.container.Panel
        GridLayout23                   matlab.ui.container.GridLayout
        EscribaeltamaofinaldelaimagenLabel  matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        InputPanel                     matlab.ui.container.Panel
        Ingreselasimgenesautilizarenelcollagemax8enelapartadoLabel  matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        GridLayout22                   matlab.ui.container.GridLayout
        RedistribuirButton             matlab.ui.control.Button
        GenerarcollageButton           matlab.ui.control.Button
        Tab_RotacionEspejo             matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        EspejoPanel                    matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        AplicarefectoespejoButton      matlab.ui.control.Button
        RotacinPanel                   matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        RotarderechaButton             matlab.ui.control.Button
        RotarizquierdaButton           matlab.ui.control.Button
        Rotarimagen45Label             matlab.ui.control.Label
        Tab_Filtros                    matlab.ui.container.Tab
        GridLayout16                   matlab.ui.container.GridLayout
        FiltradoespacialordennPanel    matlab.ui.container.Panel
        GridLayout20                   matlab.ui.container.GridLayout
        SeleccioneordenSpinner         matlab.ui.control.Spinner
        SeleccioneordenSpinnerLabel    matlab.ui.control.Label
        AplicarfiltroButton            matlab.ui.control.Button
        FiltradoespacialordenestadsticoPanel  matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        MnimoButton                    matlab.ui.control.Button
        MximoButton                    matlab.ui.control.Button
        ModaButton                     matlab.ui.control.Button
        MedianaButton                  matlab.ui.control.Button
        FiltradoespacialPanel          matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        Laplaciano8vecinosButton       matlab.ui.control.Button
        Laplaciano4vecinosButton       matlab.ui.control.Button
        GaussianoButton                matlab.ui.control.Button
        CajaomediaButton               matlab.ui.control.Button
        FiltrosparabordesPanel         matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        RobertsButton                  matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        Tab_OperacionesMorfolgicas     matlab.ui.container.Tab
        GridLayout24                   matlab.ui.container.GridLayout
        OperacionesmorfolgicasPanel    matlab.ui.container.Panel
        GridLayout26                   matlab.ui.container.GridLayout
        ErosinButton                   matlab.ui.control.Button
        DilatacinButton                matlab.ui.control.Button
        DefinicindelelementoestructurantePanel  matlab.ui.container.Panel
        GridLayout25                   matlab.ui.container.GridLayout
        ElementoEstructuranteTextArea  matlab.ui.control.TextArea
        ElementoEstructuranteTextAreaLabel  matlab.ui.control.Label
        ConsideracinesalingresarelelementoestructurantePanel  matlab.ui.container.Panel
        GridLayout27                   matlab.ui.container.GridLayout
        ParaingresarlamatrizdelelementoestructuranteseparelasLabel  matlab.ui.control.Label
        Tab_Segmentacion               matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_AbrirImg1
        function Button_AbrirImg1Pushed(app, event)
            global file1
            global img1
            global map1

            [img1, map1, file1, path1, indx1] = abrirImagen(app.UIAxes_ImgOriginal1);
            
            % Colocar imagen en UIAxes principal
            imshow(img1, 'Parent', app.UIAxes_ImgOriginal);

            % Hacer copia (donde se realizarán las modificaciones)
            global imgProcesada
            global mapProcesada
            imgProcesada = img1;
            mapProcesada = map1;
            imshow(imgProcesada, 'Parent', app.UIAxes_ImgProcesada);

            % Hacer copia para funcionalidad de 'deshacer'
            global imgDeshacer
            global mapDeshacer
            imgDeshacer = img1;
            mapDeshacer = map1;
        end

        % Button pushed function: Button_AbrirImg2
        function Button_AbrirImg2Pushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProyectoProcesamientodeImgenesDigitalesUIFigure = uifigure('Visible', 'off');
            app.ProyectoProcesamientodeImgenesDigitalesUIFigure.Name = 'Proyecto: Procesamiento de Imágenes Digitales';
            app.GridLayout = uigridlayout(app.ProyectoProcesamientodeImgenesDigitalesUIFigure);
            app.MenuTabGroup = uitabgroup(app.GridLayout);
            app.Tab_OperacionesBasicas = uitab(app.MenuTabGroup);
            app.Tab_OperacionesBasicas.Title = 'Operaciones básicas';
            app.GridLayout2 = uigridlayout(app.Tab_OperacionesBasicas);
            app.EcualizacinPanel = uipanel(app.GridLayout2);
            app.EcualizacinPanel.Title = 'Ecualización';
            app.GridLayout10 = uigridlayout(app.EcualizacinPanel);
            app.EcualizarButton = uibutton(app.GridLayout10, 'push');
            app.EcualizarButton.Text = 'Ecualizar';
            app.MostrarhistogramasButton = uibutton(app.GridLayout10, 'push');
            app.MostrarhistogramasButton.Text = 'Mostrar histogramas';
            app.InversinPanel = uipanel(app.GridLayout2);
            app.InversinPanel.Title = 'Inversión';
            app.GridLayout11 = uigridlayout(app.InversinPanel);
            app.InvertirbinariaButton = uibutton(app.GridLayout11, 'push');
            app.InvertirbinariaButton.Text = 'Invertir (binaria)';
            app.InvertirfotogrficaButton = uibutton(app.GridLayout11, 'push');
            app.InvertirfotogrficaButton.Text = 'Invertir (fotográfica)';
            app.AdicinysustraccinPanel = uipanel(app.GridLayout2);
            app.AdicinysustraccinPanel.Title = 'Adición y sustracción';
            app.GridLayout12 = uigridlayout(app.AdicinysustraccinPanel);
            app.AdicinButton = uibutton(app.GridLayout12, 'push');
            app.AdicinButton.Text = 'Adición';
            app.SustraccinButton = uibutton(app.GridLayout12, 'push');
            app.SustraccinButton.Text = 'Sustracción';
            app.Requieredeimagen1imagen2Label = uilabel(app.GridLayout12);
            app.Requieredeimagen1imagen2Label.Text = 'Requiere de imagen 1 & imagen 2';
            app.Tab_Collage = uitab(app.MenuTabGroup);
            app.Tab_Collage.Title = 'Collage';
            app.GridLayout21 = uigridlayout(app.Tab_Collage);
            app.OutputPanel = uipanel(app.GridLayout21);
            app.OutputPanel.Title = 'Output';
            app.GridLayout22 = uigridlayout(app.OutputPanel);
            app.GenerarcollageButton = uibutton(app.GridLayout22, 'push');
            app.GenerarcollageButton.Text = 'Generar collage';
            app.RedistribuirButton = uibutton(app.GridLayout22, 'push');
            app.RedistribuirButton.Text = 'Redistribuir';
            app.InputPanel = uipanel(app.GridLayout21);
            app.InputPanel.Title = 'Input';
            app.Ingreselasimgenesautilizarenelcollagemax8enelapartadoLabel = uilabel(app.InputPanel);
            app.Ingreselasimgenesautilizarenelcollagemax8enelapartadoLabel.Text = 'Ingrese las imágenes a utilizar en el collage (max.8) en las pestañas correspondientes en el apartado de ''Imágenes originales''';
            app.ConfiguracinPanel = uipanel(app.GridLayout21);
            app.ConfiguracinPanel.Title = 'Configuración';
            app.GridLayout23 = uigridlayout(app.ConfiguracinPanel);
            app.WidthEditFieldLabel = uilabel(app.GridLayout23);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.GridLayout23, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.GridLayout23);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.GridLayout23, 'numeric');
            app.EscribaeltamaofinaldelaimagenLabel = uilabel(app.GridLayout23);
            app.EscribaeltamaofinaldelaimagenLabel.Text = 'Escriba el tamaño final de la imagen:';
            app.Tab_RotacionEspejo = uitab(app.MenuTabGroup);
            app.Tab_RotacionEspejo.Title = 'Rotación y espejo';
            app.GridLayout13 = uigridlayout(app.Tab_RotacionEspejo);
            app.RotacinPanel = uipanel(app.GridLayout13);
            app.RotacinPanel.Title = 'Rotación';
            app.GridLayout14 = uigridlayout(app.RotacinPanel);
            app.Rotarimagen45Label = uilabel(app.GridLayout14);
            app.Rotarimagen45Label.Text = 'Rotar imagen 45º';
            app.RotarizquierdaButton = uibutton(app.GridLayout14, 'push');
            app.RotarizquierdaButton.Text = 'Rotar izquierda';
            app.RotarderechaButton = uibutton(app.GridLayout14, 'push');
            app.RotarderechaButton.Text = 'Rotar derecha';
            app.EspejoPanel = uipanel(app.GridLayout13);
            app.EspejoPanel.Title = 'Espejo';
            app.GridLayout15 = uigridlayout(app.EspejoPanel);
            app.AplicarefectoespejoButton = uibutton(app.GridLayout15, 'push');
            app.AplicarefectoespejoButton.Text = 'Aplicar efecto espejo';
            app.Tab_Filtros = uitab(app.MenuTabGroup);
            app.Tab_Filtros.Title = 'Filtros';
            app.GridLayout16 = uigridlayout(app.Tab_Filtros);
            app.FiltrosparabordesPanel = uipanel(app.GridLayout16);
            app.FiltrosparabordesPanel.Title = 'Filtros para bordes';
            app.GridLayout17 = uigridlayout(app.FiltrosparabordesPanel);
            app.PrewittButton = uibutton(app.GridLayout17, 'push');
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.GridLayout17, 'push');
            app.SobelButton.Text = 'Sobel';
            app.RobertsButton = uibutton(app.GridLayout17, 'push');
            app.RobertsButton.Text = 'Roberts';
            app.FiltradoespacialPanel = uipanel(app.GridLayout16);
            app.FiltradoespacialPanel.Title = 'Filtrado espacial';
            app.GridLayout18 = uigridlayout(app.FiltradoespacialPanel);
            app.CajaomediaButton = uibutton(app.GridLayout18, 'push');
            app.CajaomediaButton.Text = 'Caja o media';
            app.GaussianoButton = uibutton(app.GridLayout18, 'push');
            app.GaussianoButton.Text = 'Gaussiano';
            app.Laplaciano4vecinosButton = uibutton(app.GridLayout18, 'push');
            app.Laplaciano4vecinosButton.Text = 'Laplaciano 4-vecinos';
            app.Laplaciano8vecinosButton = uibutton(app.GridLayout18, 'push');
            app.Laplaciano8vecinosButton.Text = 'Laplaciano 8-vecinos';
            app.FiltradoespacialordenestadsticoPanel = uipanel(app.GridLayout16);
            app.FiltradoespacialordenestadsticoPanel.Title = 'Filtrado espacial: orden estadístico';
            app.GridLayout19 = uigridlayout(app.FiltradoespacialordenestadsticoPanel);
            app.MedianaButton = uibutton(app.GridLayout19, 'push');
            app.MedianaButton.Text = 'Mediana';
            app.ModaButton = uibutton(app.GridLayout19, 'push');
            app.ModaButton.Text = 'Moda';
            app.MximoButton = uibutton(app.GridLayout19, 'push');
            app.MximoButton.Text = 'Máximo';
            app.MnimoButton = uibutton(app.GridLayout19, 'push');
            app.MnimoButton.Text = 'Mínimo';
            app.FiltradoespacialordennPanel = uipanel(app.GridLayout16);
            app.FiltradoespacialordennPanel.Title = 'Filtrado espacial: orden n';
            app.GridLayout20 = uigridlayout(app.FiltradoespacialordennPanel);
            app.AplicarfiltroButton = uibutton(app.GridLayout20, 'push');
            app.AplicarfiltroButton.Text = 'Aplicar filtro';
            app.SeleccioneordenSpinnerLabel = uilabel(app.GridLayout20);
            app.SeleccioneordenSpinnerLabel.Text = 'Seleccione orden';
            app.SeleccioneordenSpinner = uispinner(app.GridLayout20);
            app.SeleccioneordenSpinner.Limits = [1 9];
            app.SeleccioneordenSpinner.Value = 1;
            app.Tab_OperacionesMorfolgicas = uitab(app.MenuTabGroup);
            app.Tab_OperacionesMorfolgicas.Title = 'Operaciones morfológicas';
            app.GridLayout24 = uigridlayout(app.Tab_OperacionesMorfolgicas);
            app.ConsideracinesalingresarelelementoestructurantePanel = uipanel(app.GridLayout24);
            app.ConsideracinesalingresarelelementoestructurantePanel.Title = 'Consideraciónes al ingresar el elemento estructurante';
            app.GridLayout27 = uigridlayout(app.ConsideracinesalingresarelelementoestructurantePanel);
            app.ParaingresarlamatrizdelelementoestructuranteseparelasLabel = uilabel(app.GridLayout27);
            app.ParaingresarlamatrizdelelementoestructuranteseparelasLabel.Text = 'Para ingresar la matriz del elemento estructurante, inicie la definición de la matriz con un "[" y terminela con "]". Además, separe los elementos en cada renglón con un espacio y los renglones con un punto y coma (;). Puede dar saltos de línea entre renglones. Ejemplo: [1 3 5; 2 4 6; 7 8 10]';
            app.DefinicindelelementoestructurantePanel = uipanel(app.GridLayout24);
            app.DefinicindelelementoestructurantePanel.Title = 'Definición del elemento estructurante';
            app.GridLayout25 = uigridlayout(app.DefinicindelelementoestructurantePanel);
            app.ElementoEstructuranteTextAreaLabel = uilabel(app.GridLayout25);
            app.ElementoEstructuranteTextAreaLabel.Text = 'Elemento Estructurante:';
            app.ElementoEstructuranteTextArea = uitextarea(app.GridLayout25);
            app.OperacionesmorfolgicasPanel = uipanel(app.GridLayout24);
            app.OperacionesmorfolgicasPanel.Title = 'Operaciones morfológicas';
            app.GridLayout26 = uigridlayout(app.OperacionesmorfolgicasPanel);
            app.DilatacinButton = uibutton(app.GridLayout26, 'push');
            app.DilatacinButton.Text = 'Dilatación';
            app.ErosinButton = uibutton(app.GridLayout26, 'push');
            app.ErosinButton.Text = 'Erosión';
            app.Tab_Segmentacion = uitab(app.MenuTabGroup);
            app.Tab_Segmentacion.Title = 'Segmentación';
            app.Panel_ImgProcesada = uipanel(app.GridLayout);
            app.Panel_ImgProcesada.Title = 'Imagen procesada';
            app.GridLayout6 = uigridlayout(app.Panel_ImgProcesada);
            app.UIAxes_ImgProcesada = uiaxes(app.GridLayout6);
            title(app.UIAxes_ImgProcesada, 'Imagen procesada')
            xlabel(app.UIAxes_ImgProcesada, 'X')
            ylabel(app.UIAxes_ImgProcesada, 'Y')
            zlabel(app.UIAxes_ImgProcesada, 'Z')
            app.Panel_ConversionGrayscle = uipanel(app.GridLayout6);
            app.Panel_ConversionGrayscle.Title = 'Conversión a grayscale para filtros & ecualización';
            app.GridLayout8 = uigridlayout(app.Panel_ConversionGrayscle);
            app.Button_Convertir = uibutton(app.GridLayout8, 'push');
            app.Button_Convertir.Text = 'Convertir';
            app.Panel_ArchivoProcesada = uipanel(app.GridLayout6);
            app.Panel_ArchivoProcesada.Title = 'Archivo';
            app.GridLayout9 = uigridlayout(app.Panel_ArchivoProcesada);
            app.Button_Deshacer = uibutton(app.GridLayout9, 'push');
            app.Button_Deshacer.Text = 'Deshacer';
            app.Button_Guardar = uibutton(app.GridLayout9, 'push');
            app.Button_Guardar.Text = 'Guardar';
            app.TabGroup_ImgOriginal = uitabgroup(app.GridLayout);
            app.Tab_ImgOriginal1 = uitab(app.TabGroup_ImgOriginal);
            app.Tab_ImgOriginal1.Title = 'Imagen original 1';
            app.Grid_ImgOriginal = uigridlayout(app.Tab_ImgOriginal1);
            app.UIAxes_ImgOriginal = uiaxes(app.Grid_ImgOriginal);
            title(app.UIAxes_ImgOriginal, 'Imagen original 1')
            xlabel(app.UIAxes_ImgOriginal, 'X')
            ylabel(app.UIAxes_ImgOriginal, 'Y')
            zlabel(app.UIAxes_ImgOriginal, 'Z')
            app.Panel_Archivo = uipanel(app.Grid_ImgOriginal);
            app.Panel_Archivo.Title = 'Archivo';
            app.Grid_Archivo = uigridlayout(app.Panel_Archivo);
            app.Button_QuitarImg1 = uibutton(app.Grid_Archivo, 'push');
            app.Button_QuitarImg1.Text = 'Quitar';
            app.Button_AbrirImg1 = uibutton(app.Grid_Archivo, 'push');
            app.Button_AbrirImg1.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg1Pushed, true);
            app.Button_AbrirImg1.Text = 'Abrir imagen';
            app.Tab_ImgOriginal4 = uitab(app.TabGroup_ImgOriginal);
            app.Tab_ImgOriginal4.Title = 'Imágenes originales (1-4) ';
            app.Grid_ImgOriginal4 = uigridlayout(app.Tab_ImgOriginal4);
            app.UIAxes_ImgOriginal4 = uiaxes(app.Grid_ImgOriginal4);
            title(app.UIAxes_ImgOriginal4, 'Imagen original 4')
            xlabel(app.UIAxes_ImgOriginal4, 'X')
            ylabel(app.UIAxes_ImgOriginal4, 'Y')
            zlabel(app.UIAxes_ImgOriginal4, 'Z')
            app.UIAxes_ImgOriginal3 = uiaxes(app.Grid_ImgOriginal4);
            title(app.UIAxes_ImgOriginal3, 'Imagen original 3')
            xlabel(app.UIAxes_ImgOriginal3, 'X')
            ylabel(app.UIAxes_ImgOriginal3, 'Y')
            zlabel(app.UIAxes_ImgOriginal3, 'Z')
            app.UIAxes_ImgOriginal2 = uiaxes(app.Grid_ImgOriginal4);
            title(app.UIAxes_ImgOriginal2, 'Imagen original 2')
            xlabel(app.UIAxes_ImgOriginal2, 'X')
            ylabel(app.UIAxes_ImgOriginal2, 'Y')
            zlabel(app.UIAxes_ImgOriginal2, 'Z')
            app.UIAxes_ImgOriginal1 = uiaxes(app.Grid_ImgOriginal4);
            title(app.UIAxes_ImgOriginal1, 'Imagen original 1')
            xlabel(app.UIAxes_ImgOriginal1, 'X')
            ylabel(app.UIAxes_ImgOriginal1, 'Y')
            zlabel(app.UIAxes_ImgOriginal1, 'Z')
            app.Button_QuitarImg4 = uibutton(app.Grid_ImgOriginal4, 'push');
            app.Button_QuitarImg4.Text = 'Quitar';
            app.Button_QuitarImg3 = uibutton(app.Grid_ImgOriginal4, 'push');
            app.Button_QuitarImg3.Text = 'Quitar';
            app.Button_QuitarImg2 = uibutton(app.Grid_ImgOriginal4, 'push');
            app.Button_QuitarImg2.Text = 'Quitar';
            app.Button_AbrirImg4 = uibutton(app.Grid_ImgOriginal4, 'push');
            app.Button_AbrirImg4.Text = 'Abrir imagen 4';
            app.Button_AbrirImg3 = uibutton(app.Grid_ImgOriginal4, 'push');
            app.Button_AbrirImg3.Text = 'Abrir imagen 3';
            app.Button_AbrirImg2 = uibutton(app.Grid_ImgOriginal4, 'push');
            app.Button_AbrirImg2.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg2Pushed, true);
            app.Button_AbrirImg2.Text = 'Abrir imagen 2';
            app.Tab_ImgOriginal8 = uitab(app.TabGroup_ImgOriginal);
            app.Tab_ImgOriginal8.Title = 'Imágenes originales (5-8)';
            app.Grid_ImgOriginal8 = uigridlayout(app.Tab_ImgOriginal8);
            app.UIAxes_ImgOriginal8 = uiaxes(app.Grid_ImgOriginal8);
            title(app.UIAxes_ImgOriginal8, 'Imagen original 8')
            xlabel(app.UIAxes_ImgOriginal8, 'X')
            ylabel(app.UIAxes_ImgOriginal8, 'Y')
            zlabel(app.UIAxes_ImgOriginal8, 'Z')
            app.UIAxes_ImgOriginal7 = uiaxes(app.Grid_ImgOriginal8);
            title(app.UIAxes_ImgOriginal7, 'Imagen original 7')
            xlabel(app.UIAxes_ImgOriginal7, 'X')
            ylabel(app.UIAxes_ImgOriginal7, 'Y')
            zlabel(app.UIAxes_ImgOriginal7, 'Z')
            app.UIAxes_ImgOriginal6 = uiaxes(app.Grid_ImgOriginal8);
            title(app.UIAxes_ImgOriginal6, 'Imagen original 6')
            xlabel(app.UIAxes_ImgOriginal6, 'X')
            ylabel(app.UIAxes_ImgOriginal6, 'Y')
            zlabel(app.UIAxes_ImgOriginal6, 'Z')
            app.UIAxes_ImgOriginal5 = uiaxes(app.Grid_ImgOriginal8);
            title(app.UIAxes_ImgOriginal5, 'Imagen original 5')
            xlabel(app.UIAxes_ImgOriginal5, 'X')
            ylabel(app.UIAxes_ImgOriginal5, 'Y')
            zlabel(app.UIAxes_ImgOriginal5, 'Z')
            app.Button_QuitarImg8 = uibutton(app.Grid_ImgOriginal8, 'push');
            app.Button_QuitarImg8.Text = 'Quitar';
            app.Button_QuitarImg7 = uibutton(app.Grid_ImgOriginal8, 'push');
            app.Button_QuitarImg7.Text = 'Quitar';
            app.Button_QuitarImg6 = uibutton(app.Grid_ImgOriginal8, 'push');
            app.Button_QuitarImg6.Text = 'Quitar';
            app.Button_QuitarImg5 = uibutton(app.Grid_ImgOriginal8, 'push');
            app.Button_QuitarImg5.Text = 'Quitar';
            app.Button_AbrirImg8 = uibutton(app.Grid_ImgOriginal8, 'push');
            app.Button_AbrirImg8.Text = 'Abrir imagen 8';
            app.Button_AbrirImg7 = uibutton(app.Grid_ImgOriginal8, 'push');
            app.Button_AbrirImg7.Text = 'Abrir imagen 7';
            app.Button_AbrirImg6 = uibutton(app.Grid_ImgOriginal8, 'push');
            app.Button_AbrirImg6.Text = 'Abrir imagen 6';
            app.Button_AbrirImg5 = uibutton(app.Grid_ImgOriginal8, 'push');
            app.Button_AbrirImg5.Text = 'Abrir imagen 5';
            % Show the figure after all components are created
            app.ProyectoProcesamientodeImgenesDigitalesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vista

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProyectoProcesamientodeImgenesDigitalesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProyectoProcesamientodeImgenesDigitalesUIFigure)
        end
    end
end