classdef aplicacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProyectoProcesamientodeImgenesDigitalesUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup_ImgOriginal           matlab.ui.container.TabGroup
        Tab_ImgOriginal1               matlab.ui.container.Tab
        Grid_ImgOriginal               matlab.ui.container.GridLayout
        Panel_Archivo                  matlab.ui.container.Panel
        Grid_Archivo                   matlab.ui.container.GridLayout
        Button_ObtenerInfo             matlab.ui.control.Button
        Button_AbrirImg1               matlab.ui.control.Button
        Button_QuitarImg1              matlab.ui.control.Button
        UIAxes_ImgOriginal1            matlab.ui.control.UIAxes
        Tab_ImgOriginal2a5             matlab.ui.container.Tab
        Grid_ImgOriginal5              matlab.ui.container.GridLayout
        Button_AbrirImg2               matlab.ui.control.Button
        Button_AbrirImg3               matlab.ui.control.Button
        Button_AbrirImg4               matlab.ui.control.Button
        Button_AbrirImg5               matlab.ui.control.Button
        Button_QuitarImg2              matlab.ui.control.Button
        Button_QuitarImg3              matlab.ui.control.Button
        Button_QuitarImg4              matlab.ui.control.Button
        Button_QuitarImg5              matlab.ui.control.Button
        UIAxes_ImgOriginal2            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal3            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal4            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal5            matlab.ui.control.UIAxes
        Tab_ImgOriginal6a9             matlab.ui.container.Tab
        Grid_ImgOriginal9              matlab.ui.container.GridLayout
        Button_AbrirImg6               matlab.ui.control.Button
        Button_AbrirImg7               matlab.ui.control.Button
        Button_AbrirImg8               matlab.ui.control.Button
        Button_AbrirImg9               matlab.ui.control.Button
        Button_QuitarImg6              matlab.ui.control.Button
        Button_QuitarImg7              matlab.ui.control.Button
        Button_QuitarImg8              matlab.ui.control.Button
        Button_QuitarImg9              matlab.ui.control.Button
        UIAxes_ImgOriginal9            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal8            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal7            matlab.ui.control.UIAxes
        UIAxes_ImgOriginal6            matlab.ui.control.UIAxes
        Panel_ImgProcesada             matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        Panel_ArchivoProcesada         matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        Button_DeshacerTodo            matlab.ui.control.Button
        Button_Guardar                 matlab.ui.control.Button
        Button_Deshacer                matlab.ui.control.Button
        Panel_ConversionGrayscle       matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        DropDown_Convertir             matlab.ui.control.DropDown
        ConvertiraDropDownLabel        matlab.ui.control.Label
        UIAxes_ImgProcesada            matlab.ui.control.UIAxes
        MenuTabGroup                   matlab.ui.container.TabGroup
        Tab_OperacionesBasicas         matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        AdicinysustraccinPanel         matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        Requieredeimagen1imagen2Label  matlab.ui.control.Label
        SustraccinButton               matlab.ui.control.Button
        AdicinButton                   matlab.ui.control.Button
        InversinPanel                  matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        InvertirfotogrficaButton       matlab.ui.control.Button
        InvertirbinariaButton          matlab.ui.control.Button
        EcualizacinPanel               matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        MostrarhistogramasButton       matlab.ui.control.Button
        EcualizarButton                matlab.ui.control.Button
        Tab_Collage                    matlab.ui.container.Tab
        GridLayout21                   matlab.ui.container.GridLayout
        ConfiguracinPanel              matlab.ui.container.Panel
        GridLayout23                   matlab.ui.container.GridLayout
        EscribaeltamaofinaldelaimagenLabel  matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        WidthEditFieldLabel            matlab.ui.control.Label
        InputPanel                     matlab.ui.container.Panel
        Ingreselasimgenesautilizarenelcollagemax8enelapartadoLabel  matlab.ui.control.Label
        OutputPanel                    matlab.ui.container.Panel
        GridLayout22                   matlab.ui.container.GridLayout
        RedistribuirButton             matlab.ui.control.Button
        GenerarcollageButton           matlab.ui.control.Button
        Tab_RotacionEspejo             matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        EspejoPanel                    matlab.ui.container.Panel
        GridLayout15                   matlab.ui.container.GridLayout
        AplicarefectoespejoButton      matlab.ui.control.Button
        RotacinPanel                   matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        RotarderechaButton             matlab.ui.control.Button
        RotarizquierdaButton           matlab.ui.control.Button
        Rotarimagen45Label             matlab.ui.control.Label
        Tab_Filtros                    matlab.ui.container.Tab
        GridLayout16                   matlab.ui.container.GridLayout
        FiltradoespacialordennPanel    matlab.ui.container.Panel
        GridLayout20                   matlab.ui.container.GridLayout
        SeleccioneordenSpinner         matlab.ui.control.Spinner
        SeleccioneordenSpinnerLabel    matlab.ui.control.Label
        AplicarfiltroButton            matlab.ui.control.Button
        FiltradoespacialordenestadsticoPanel  matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        MnimoButton                    matlab.ui.control.Button
        MximoButton                    matlab.ui.control.Button
        ModaButton                     matlab.ui.control.Button
        MedianaButton                  matlab.ui.control.Button
        FiltradoespacialPanel          matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        Laplaciano8vecinosButton       matlab.ui.control.Button
        Laplaciano4vecinosButton       matlab.ui.control.Button
        GaussianoButton                matlab.ui.control.Button
        CajaomediaButton               matlab.ui.control.Button
        FiltrosparabordesPanel         matlab.ui.container.Panel
        GridLayout17                   matlab.ui.container.GridLayout
        RobertsButton                  matlab.ui.control.Button
        SobelButton                    matlab.ui.control.Button
        PrewittButton                  matlab.ui.control.Button
        Tab_OperacionesMorfolgicas     matlab.ui.container.Tab
        GridLayout24                   matlab.ui.container.GridLayout
        OperacionesmorfolgicasPanel    matlab.ui.container.Panel
        GridLayout26                   matlab.ui.container.GridLayout
        ErosinButton                   matlab.ui.control.Button
        DilatacinButton                matlab.ui.control.Button
        DefinicindelelementoestructurantePanel  matlab.ui.container.Panel
        GridLayout25                   matlab.ui.container.GridLayout
        ElementoEstructuranteTextArea  matlab.ui.control.TextArea
        ElementoEstructuranteTextAreaLabel  matlab.ui.control.Label
        ConsideracinesalingresarelelementoestructurantePanel  matlab.ui.container.Panel
        GridLayout27                   matlab.ui.container.GridLayout
        ParaingresarlamatrizdelelementoestructuranteseparelasLabel  matlab.ui.control.Label
        Tab_Segmentacion               matlab.ui.container.Tab
        GridLayout28                   matlab.ui.container.GridLayout
        SegmentacionsinCoordenadasparacrecimientoderegionesPanel  matlab.ui.container.Panel
        ParaingresarlamatrizdelelementoestructuranteseparelasLabel_3  matlab.ui.control.Label
        CrecimientodeRegionesPanel     matlab.ui.container.Panel
        SegmentarsincoordenadasButton  matlab.ui.control.Button
        SegmentarButton                matlab.ui.control.Button
        puntoXSeg                      matlab.ui.control.NumericEditField
        puntoYSeg                      matlab.ui.control.NumericEditField
        XLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        CrecimientodeRegionesConsideracionesPanel  matlab.ui.container.Panel
        ParaingresarlamatrizdelelementoestructuranteseparelasLabel_2  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Matrices de las imágenes
        img1 = []
        img2 = []
        img3 = []
        img4 = []
        img5 = []
        img6 = []
        img7 = []
        img8 = []
        img9 = []
        imgProcesada = []
        imgDeshacer  = []
        % Mapa de color de las imágenes
        map1 = []
        map2 = []
        map3 = []
        map4 = []
        map5 = []
        map6 = []
        map7 = []
        map8 = []
        map9 = []
        mapProcesada = []
        mapDeshacer  = []
        % Rutas de las imágenes originales
        path1 = []
        path2 = []
        path3 = []
        path4 = []
        path5 = []
        path6 = []
        path7 = []
        path8 = []
        path9 = []
        % Tipos de las imágenes
        type1 = []
        type2 = []
        type3 = []
        type4 = []
        type5 = []
        type6 = []
        type7 = []
        type8 = []
        type9 = []
        typeProcesada = []
        typeDeshacer  = []
        % Histogramas
        histOriginal   = []
        histEcualizado = []
        imgEcualizada  = []
        % elemento estructurante
        elemento
        % coordenadas segmentacion por crecimiento de regiones
        coordenadaX
        coordenadaY
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Deshacer
        function Button_DeshacerPushed(app, event)
            if isempty(app.imgProcesada), return, end

            app.imgProcesada  = app.imgDeshacer;
            app.mapProcesada  = app.mapDeshacer;
            app.typeProcesada = app.typeDeshacer;

            imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);
        end

        % Button pushed function: Button_DeshacerTodo
        function Button_DeshacerTodoPushed(app, event)
            if isempty(app.imgProcesada), return, end

            app.imgProcesada  = app.img1;
            app.mapProcesada  = app.map1;
            app.typeProcesada = app.type1;
            
            imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada); 
        end

        % Button pushed function: Button_Guardar
        function Button_GuardarPushed(app, event)
            % Guardar imagen procesada
            Guardar(app.imgProcesada, app.mapProcesada, app.typeProcesada);
        end

        % Value changed function: DropDown_Convertir
        function DropDown_ConvertirValueChanged(app, event)
            if isempty(app.imgProcesada), return, end
            
            selection = app.DropDown_Convertir.Value;

            if selection=="Seleccionar tipo", return, end

            % Conversion de la imagen
            [imgC, mapC, typeC] = convertirImagen(selection, ...
                app.imgProcesada, app.mapProcesada, app.typeProcesada);

            if ~isequal(app.typeProcesada, typeC)
                
                % Actualizacion de imagen anterior
                app.imgDeshacer  = app.imgProcesada;
                app.mapDeshacer  = app.mapProcesada;
                app.typeDeshacer = app.typeProcesada;

                app.imgProcesada  = imgC;
                app.mapProcesada  = mapC;
                app.typeProcesada = typeC;

                if app.typeProcesada == "indexed"
                    imshow(app.imgProcesada, app.mapProcesada, 'Parent', app.UIAxes_ImgProcesada);
                else
                    imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);
                end
                
                app.DropDown_Convertir.Value = "Seleccionar tipo";

            end
        end

        % Button pushed function: EcualizarButton
        function EcualizarButtonPushed(app, event)
            if isempty(app.imgProcesada), return, end

            try
                [imgE, histO, histE] = ecualizar(app.imgProcesada, app.typeProcesada);

                % Actualización de imagen anterior
                app.imgDeshacer  = app.imgProcesada;
                app.mapDeshacer  = app.mapProcesada;
                app.typeDeshacer = app.typeProcesada;
    
                % Ecualización
                app.imgProcesada   = imgE;
                app.histOriginal   = histO;
                app.histEcualizado = histE;
                app.imgEcualizada  = imgE;
                imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);

            catch ME
                msgbox(ME.message);
                return
            end
        end

        % Button pushed function: MostrarhistogramasButton
        function MostrarhistogramasButtonPushed(app, event)
            if isempty(app.imgProcesada), return, end
            
            try
                mostrarHistrogramas(app.histOriginal, app.histEcualizado, ...
                    app.imgProcesada, app.imgEcualizada);
            catch ME
                msgbox(ME.message);
                return
            end
        end

        % Button pushed function: CajaomediaButton
        function CajaomediaButtonPushed(app, event)

            imgCaja = filtroCaja(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgCaja, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgCaja;
        end

        % Button pushed function: GaussianoButton
        function GaussianoButtonPushed(app, event)

            imgGauss = filtroGaussiano(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgGauss, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgGauss;
        end

        % Button pushed function: MedianaButton
        function MedianaButtonPushed(app, event)

            imgMediana = filtroMediana(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgMediana, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgMediana;
        end

        % Button pushed function: ModaButton
        function ModaButtonPushed(app, event)

            imgModa = filtroModa(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgModa, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgModa;            
        end

        % Button pushed function: MximoButton
        function MximoButtonPushed(app, event)

            imgMaximo = filtroMaximo(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgMaximo, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgMaximo;    
        end

        % Button pushed function: MnimoButton
        function MnimoButtonPushed(app, event)

            imgMinimo = filtroMinimo(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgMinimo, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgMinimo;          
        end

        % Button pushed function: AplicarfiltroButton
        function AplicarfiltroButtonPushed(app, event)

            if size(app.imgProcesada, 3) == 3 % RGB

                imagen_gris = rgb2gray(app.imgProcesada);
                tamano_filtro_str = app.SeleccioneordenSpinner.Value;
                sizeFiltro = tamano_filtro_str;
                filtroN = ones(sizeFiltro) / (sizeFiltro ^ 2);
                tipoNFilter = imfilter(imagen_gris, filtroN);

            elseif islogical(app.imgProcesada) % Binaria

                imagen_gris = uint8(app.imgProcesada) * 255;
                tamano_filtro_str = app.SeleccioneordenSpinner.Value;
                sizeFiltro = tamano_filtro_str;
                filtroN = ones(sizeFiltro) / (sizeFiltro ^ 2);
                tipoNFilter = imfilter(imagen_gris, filtroN);

            else % Escala de grises
                
                imagen_gris = app.imgProcesada;
                tamano_filtro_str = app.SeleccioneordenSpinner.Value;
                sizeFiltro = tamano_filtro_str;
                filtroN = ones(sizeFiltro) / (sizeFiltro ^ 2);
                tipoNFilter = imfilter(imagen_gris, filtroN);

            end

            % Colocar imagen en UIAxes principal
            imshow(tipoNFilter, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = tipoNFilter; 

        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)

            imgRoberts = filtroRoberts(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(cat(3, imgRoberts, []), 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgRoberts;  
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)

            imgSobel = filtroSobel(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgSobel, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgSobel;    
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)

            imgPrewitt = filtroPrewitt(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgPrewitt, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgPrewitt;   
        end

        % Button pushed function: InvertirbinariaButton
        function InvertirbinariaButtonPushed(app, event)
            if isempty(app.imgProcesada), return, end

            try
                % Inversion binaria
                imgBinaria = inversionBinaria(app.imgProcesada, app.typeProcesada);
                % Actualizacion de imagen anterior (para usar deshacer)
                app.imgDeshacer  = app.imgProcesada;
                app.mapDeshacer  = app.mapProcesada;
                app.typeDeshacer = app.typeProcesada;
                % se cargan cambios en imagen procesada y se muestra
                app.imgProcesada = imgBinaria;
                imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);

            catch ME
                msgbox(ME.message);
                return
            end 
        end

        % Button pushed function: InvertirfotogrficaButton
        function InvertirfotogrficaButtonPushed(app, event)
            if isempty(app.imgProcesada), return, end

            try
                % Inversion binaria
                imgInvertida = inversionFotografica(app.imgProcesada, app.typeProcesada);
                % Actualizacion de imagen anterior (para usar deshacer)
                app.imgDeshacer  = app.imgProcesada;
                app.mapDeshacer  = app.mapProcesada;
                app.typeDeshacer = app.typeProcesada;
                % se cargan cambios en imagen procesada y se muestra
                app.imgProcesada = imgInvertida;
                imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);

            catch ME
                msgbox(ME.message);
                return
            end
        end

        % Button pushed function: AdicinButton
        function AdicinButtonPushed(app, event)
            if isempty(app.imgProcesada), return, end

            % Abrir imagen adicional y mostrla en imagen 2
            [img, map, path, type] = abrirImagen();
            if isempty(img)
                return
            end
            app.img2  = img;
            app.map2  = map;
            app.path2 = path;
            app.type2 = type;
            if app.type2 == "indexed"
                imshow(app.img2, app.map2, 'Parent', app.UIAxes_ImgOriginal2);
            else
                imshow(app.img2, 'Parent', app.UIAxes_ImgOriginal2);
            end

            try
                % Inversion binaria
                imgAd = addicion(app.imgProcesada, app.typeProcesada, app.img2, app.type2);
                % Actualizacion de imagen anterior (para usar deshacer)
                app.imgDeshacer  = app.imgProcesada;
                app.mapDeshacer  = app.mapProcesada;
                app.typeDeshacer = app.typeProcesada;
                % se cargan cambios en imagen procesada y se muestra
                app.imgProcesada = imgAd;
                imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);

            catch ME
                msgbox(ME.message);
                return
            end     
        end

        % Button pushed function: SustraccinButton
        function SustraccinButtonPushed(app, event)
            if isempty(app.imgProcesada), return, end

            % Abrir imagen adicional y mostrla en imagen 2
            [img, map, path, type] = abrirImagen();
            if isempty(img)
                return
            end
            app.img2  = img;
            app.map2  = map;
            app.path2 = path;
            app.type2 = type;
            if app.type2 == "indexed"
                imshow(app.img2, app.map2, 'Parent', app.UIAxes_ImgOriginal2);
            else
                imshow(app.img2, 'Parent', app.UIAxes_ImgOriginal2);
            end

            try
                % Inversion binaria
                imgAd = sustraccion(app.imgProcesada, app.typeProcesada, app.img2, app.type2);
                % Actualizacion de imagen anterior (para usar deshacer)
                app.imgDeshacer  = app.imgProcesada;
                app.mapDeshacer  = app.mapProcesada;
                app.typeDeshacer = app.typeProcesada;
                % se cargan cambios en imagen procesada y se muestra
                app.imgProcesada = imgAd;
                imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);

            catch ME
                msgbox(ME.message);
                return
            end
        end

        % Button pushed function: RotarizquierdaButton
        function RotarizquierdaButtonPushed(app, event)
            imgRotadaIzq = rotacionIzquierda(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgRotadaIzq, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgRotadaIzq;
        end

        % Button pushed function: RotarderechaButton
        function RotarderechaButtonPushed(app, event)
            imgRotadaDer = rotacionDerecha(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgRotadaDer, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgRotadaDer;
        end

        % Button pushed function: AplicarefectoespejoButton
        function AplicarefectoespejoButtonPushed(app, event)
            imgConEspejo = espejo(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgConEspejo, 'Parent', app.UIAxes_ImgProcesada);
            
            app.imgDeshacer  = app.imgProcesada;
            app.imgProcesada = imgConEspejo;       
        end

        % Button pushed function: GenerarcollageButton
        function GenerarcollageButtonPushed(app, event)
            if isempty(app.imgProcesada), return, end

            try
                % generar collage
                imgCollage = crearCollage(app.imgProcesada, app.img2, app.img3, app.img4, app.img5, ...
                    app.img6, app.img7, app.img8, app.img9);

                width = app.WidthEditField.Value;
                height = app.HeightEditField.Value;

                imgCollage = imresize(imgCollage, [height width]);

                % Actualizacion de imagen anterior (para usar deshacer)
                app.imgDeshacer  = app.imgProcesada;
                app.mapDeshacer  = app.mapProcesada;
                app.typeDeshacer = app.typeProcesada;
                % se cargan cambios en imagen procesada y se muestra
                app.imgProcesada = imgCollage;
                imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);

            catch ME
                msgbox(ME.message);
                return
            end 
        end

        % Button pushed function: DilatacinButton
        function DilatacinButtonPushed(app, event)
            aux = app.ElementoEstructuranteTextArea.Value;

            app.elemento = aux;

            imgDilatada = operacionDilatacion(app.imgProcesada, app.elemento);
            
            % Colocar imagen en UIAxes principal
            imshow(imgDilatada, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgDilatada;  

        end

        % Button pushed function: ErosinButton
        function ErosinButtonPushed(app, event)
            aux = app.ElementoEstructuranteTextArea.Value;

            app.elemento = aux;

            imgErosionada = operacionErosion(app.imgProcesada, app.elemento);
            
            % Colocar imagen en UIAxes principal
            imshow(imgErosionada, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgErosionada;  

        end

        % Button pushed function: Button_ObtenerInfo
        function Button_ObtenerInfoPushed(app, event)
            % Consultar información de imagen
            consultarInformacion(app.path1);
        end

        % Button pushed function: Button_AbrirImg1
        function Button_AbrirImg1Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img1  = img;
            app.map1  = map;
            app.path1 = path;
            app.type1 = type;
            if app.type1 == "indexed"
                imshow(app.img1, app.map1, 'Parent', app.UIAxes_ImgOriginal1);
            else
                imshow(app.img1, 'Parent', app.UIAxes_ImgOriginal1);
            end
            
            % Hacer copia (donde se realizarán las modificaciones)
            app.imgProcesada  = app.img1;
            app.mapProcesada  = app.map1;
            app.typeProcesada = app.type1;
            if app.typeProcesada == "indexed"
                imshow(app.imgProcesada, app.mapProcesada, 'Parent', app.UIAxes_ImgProcesada);
            else
                imshow(app.imgProcesada, 'Parent', app.UIAxes_ImgProcesada);
            end

            % Declarar copia para funcionalidad de 'deshacer'
            app.imgDeshacer  = app.img1;
            app.mapDeshacer  = app.map1;
            app.typeDeshacer = app.type1;
        end

        % Button pushed function: Button_AbrirImg2
        function Button_AbrirImg2Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img2  = img;
            app.map2  = map;
            app.path2 = path;
            app.type2 = type;
            if app.type2 == "indexed"
                imshow(app.img2, app.map2, 'Parent', app.UIAxes_ImgOriginal2);
            else
                imshow(app.img2, 'Parent', app.UIAxes_ImgOriginal2);
            end
        end

        % Button pushed function: Button_AbrirImg3
        function Button_AbrirImg3Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img3  = img;
            app.map3  = map;
            app.path3 = path;
            app.type3 = type;
            if app.type3 == "indexed"
                imshow(app.img3, app.map3, 'Parent', app.UIAxes_ImgOriginal3);
            else
                imshow(app.img3, 'Parent', app.UIAxes_ImgOriginal3);
            end
        end

        % Button pushed function: Button_AbrirImg4
        function Button_AbrirImg4Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img4  = img;
            app.map4  = map;
            app.path4 = path;
            app.type4 = type;
            if app.type4 == "indexed"
                imshow(app.img4, app.map4, 'Parent', app.UIAxes_ImgOriginal4);
            else
                imshow(app.img4, 'Parent', app.UIAxes_ImgOriginal4);
            end
        end

        % Button pushed function: Button_AbrirImg5
        function Button_AbrirImg5Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img5  = img;
            app.map5  = map;
            app.path5 = path;
            app.type5 = type;
            if app.type5 == "indexed"
                imshow(app.img5, app.map5, 'Parent', app.UIAxes_ImgOriginal5);
            else
                imshow(app.img5, 'Parent', app.UIAxes_ImgOriginal5);
            end
        end

        % Button pushed function: Button_AbrirImg6
        function Button_AbrirImg6Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img6  = img;
            app.map6  = map;
            app.path6 = path;
            app.type6 = type;
            if app.type6 == "indexed"
                imshow(app.img6, app.map6, 'Parent', app.UIAxes_ImgOriginal6);
            else
                imshow(app.img6, 'Parent', app.UIAxes_ImgOriginal6);
            end
        end

        % Button pushed function: Button_AbrirImg7
        function Button_AbrirImg7Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img7  = img;
            app.map7  = map;
            app.path7 = path;
            app.type7 = type;
            if app.type7 == "indexed"
                imshow(app.img7, app.map7, 'Parent', app.UIAxes_ImgOriginal7);
            else
                imshow(app.img7, 'Parent', app.UIAxes_ImgOriginal7);
            end
        end

        % Button pushed function: Button_AbrirImg8
        function Button_AbrirImg8Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img8  = img;
            app.map8  = map;
            app.path8 = path;
            app.type8 = type;
            if app.type8 == "indexed"
                imshow(app.img8, app.map8, 'Parent', app.UIAxes_ImgOriginal8);
            else
                imshow(app.img8, 'Parent', app.UIAxes_ImgOriginal8);
            end
        end

        % Button pushed function: Button_AbrirImg9
        function Button_AbrirImg9Pushed(app, event)
            % Abrir imagen
            [img, map, path, type] = abrirImagen();
            
            if isempty(img)
                return
            end

            % Almacenar y mostrar imagen
            app.img9  = img;
            app.map9  = map;
            app.path9 = path;
            app.type9 = type;
            if app.type9 == "indexed"
                imshow(app.img9, app.map9, 'Parent', app.UIAxes_ImgOriginal9);
            else
                imshow(app.img9, 'Parent', app.UIAxes_ImgOriginal9);
            end
        end

        % Button pushed function: Button_QuitarImg1
        function Button_QuitarImg1Pushed(app, event)
            % Quitar imagen 1 
            cla(app.UIAxes_ImgOriginal1, 'reset');
            app.UIAxes_ImgOriginal1.Title.String = "Imagen original 1";
            app.UIAxes_ImgOriginal1.XLabel.String = "X";
            app.UIAxes_ImgOriginal1.YLabel.String = "Y";
            app.img1  = [];
            app.map1  = [];
            app.path1 = [];
            app.type1 = [];

            % Quitar imagen procesada
            cla(app.UIAxes_ImgProcesada, 'reset');
            app.UIAxes_ImgProcesada.Title.String = "Imagen procesada";
            app.UIAxes_ImgProcesada.XLabel.String = "X";
            app.UIAxes_ImgProcesada.YLabel.String = "Y";
            app.imgProcesada  = [];
            app.mapProcesada  = [];
            app.typeProcesada = [];

            % Quitar imagen procesada (deshacer)
            app.imgDeshacer  = [];
            app.mapDeshacer  = [];
            app.typeDeshacer = [];
        end

        % Button pushed function: Button_QuitarImg2
        function Button_QuitarImg2Pushed(app, event)
            % Quitar imagen 2 
            cla(app.UIAxes_ImgOriginal2, 'reset');
            app.UIAxes_ImgOriginal2.Title.String = "Imagen original 2";
            app.UIAxes_ImgOriginal2.XLabel.String = "X";
            app.UIAxes_ImgOriginal2.YLabel.String = "Y";
            app.img2  = [];
            app.map2  = [];
            app.path2 = [];
            app.type2 = [];
        end

        % Button pushed function: Button_QuitarImg3
        function Button_QuitarImg3Pushed(app, event)
            % Quitar imagen 3 
            cla(app.UIAxes_ImgOriginal3, 'reset');
            app.UIAxes_ImgOriginal3.Title.String = "Imagen original 3";
            app.UIAxes_ImgOriginal3.XLabel.String = "X";
            app.UIAxes_ImgOriginal3.YLabel.String = "Y";
            app.img3  = [];
            app.map3  = [];
            app.path3 = [];
            app.type3 = [];
        end

        % Button pushed function: Button_QuitarImg4
        function Button_QuitarImg4Pushed(app, event)
            % Quitar imagen 4
            cla(app.UIAxes_ImgOriginal4, 'reset');
            app.UIAxes_ImgOriginal4.Title.String = "Imagen original 4";
            app.UIAxes_ImgOriginal4.XLabel.String = "X";
            app.UIAxes_ImgOriginal4.YLabel.String = "Y";
            app.img4  = [];
            app.map4  = [];
            app.path4 = [];
            app.type4 = [];
        end

        % Button pushed function: Button_QuitarImg5
        function Button_QuitarImg5Pushed(app, event)
            % Quitar imagen 5 
            cla(app.UIAxes_ImgOriginal5, 'reset');
            app.UIAxes_ImgOriginal5.Title.String = "Imagen original 5";
            app.UIAxes_ImgOriginal5.XLabel.String = "X";
            app.UIAxes_ImgOriginal5.YLabel.String = "Y";
            app.img5  = [];
            app.map5  = [];
            app.path5 = [];
            app.type5 = [];
        end

        % Button pushed function: Button_QuitarImg6
        function Button_QuitarImg6Pushed(app, event)
            % Quitar imagen 6 
            cla(app.UIAxes_ImgOriginal6, 'reset');
            app.UIAxes_ImgOriginal6.Title.String = "Imagen original 6";
            app.UIAxes_ImgOriginal6.XLabel.String = "X";
            app.UIAxes_ImgOriginal6.YLabel.String = "Y";
            app.img6  = [];
            app.map6  = [];
            app.path6 = [];
            app.type6 = [];
        end

        % Button pushed function: Button_QuitarImg7
        function Button_QuitarImg7Pushed(app, event)
            % Quitar imagen 7 
            cla(app.UIAxes_ImgOriginal7, 'reset');
            app.UIAxes_ImgOriginal7.Title.String = "Imagen original 7";
            app.UIAxes_ImgOriginal7.XLabel.String = "X";
            app.UIAxes_ImgOriginal7.YLabel.String = "Y";
            app.img7  = [];
            app.map7  = [];
            app.path7 = [];
            app.type7 = [];
        end

        % Button pushed function: Button_QuitarImg8
        function Button_QuitarImg8Pushed(app, event)
            % Quitar imagen 8 
            cla(app.UIAxes_ImgOriginal8, 'reset');
            app.UIAxes_ImgOriginal8.Title.String = "Imagen original 8";
            app.UIAxes_ImgOriginal8.XLabel.String = "X";
            app.UIAxes_ImgOriginal8.YLabel.String = "Y";
            app.img8  = [];
            app.map8  = [];
            app.path8 = [];
            app.type8 = [];
        end

        % Button pushed function: Button_QuitarImg9
        function Button_QuitarImg9Pushed(app, event)
            % Quitar imagen 9 
            cla(app.UIAxes_ImgOriginal9, 'reset');
            app.UIAxes_ImgOriginal9.Title.String = "Imagen original 9";
            app.UIAxes_ImgOriginal9.XLabel.String = "X";
            app.UIAxes_ImgOriginal9.YLabel.String = "Y";
            app.img9  = [];
            app.map9  = [];
            app.path9 = [];
            app.type9 = [];
        end

        % Button pushed function: SegmentarButton
        function SegmentarButtonPushed(app, event)
            coorX = app.puntoXSeg.Value;
            coorY = app.puntoYSeg.Value;


            app.coordenadaX = coorX;
            app.coordenadaY = coorY;

            imgSegRegion = segmentacionRegiones(app.imgProcesada, app.coordenadaX, app.coordenadaY);
            
            % Colocar imagen en UIAxes principal
            imshow(imgSegRegion, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgSegRegion;  
        end

        % Button pushed function: SegmentarsincoordenadasButton
        function SegmentarsincoordenadasButtonPushed(app, event)
            imgSegRegionSC = segmentacionRegionesSinCoordenadas(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgSegRegionSC, 'Parent', app.UIAxes_ImgProcesada);
            app.imgDeshacer = app.imgProcesada;
            app.imgProcesada = imgSegRegionSC;  
        end

        % Button pushed function: Laplaciano4vecinosButton
        function Laplaciano4vecinosButtonPushed(app, event)
            imgLap4v = filtroLaplaciano4v(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgLap4v, 'Parent', app.UIAxes_ImgProcesada);
            
            app.imgDeshacer  = app.imgProcesada;
            app.imgProcesada = imgLap4v;
        end

        % Button pushed function: Laplaciano8vecinosButton
        function Laplaciano8vecinosButtonPushed(app, event)
            imgLap8v = filtroLaplaciano8v(app.imgProcesada);
            
            % Colocar imagen en UIAxes principal
            imshow(imgLap8v, 'Parent', app.UIAxes_ImgProcesada);
            
            app.imgDeshacer  = app.imgProcesada;
            app.imgProcesada = imgLap8v;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProyectoProcesamientodeImgenesDigitalesUIFigure = uifigure('Visible', 'off');
            app.ProyectoProcesamientodeImgenesDigitalesUIFigure.Name = 'Proyecto: Procesamiento de Imágenes Digitales';
            app.GridLayout = uigridlayout(app.ProyectoProcesamientodeImgenesDigitalesUIFigure);
            app.MenuTabGroup = uitabgroup(app.GridLayout);
            app.Tab_OperacionesBasicas = uitab(app.MenuTabGroup);
            app.Tab_OperacionesBasicas.Title = 'Operaciones básicas';
            app.GridLayout2 = uigridlayout(app.Tab_OperacionesBasicas);
            app.EcualizacinPanel = uipanel(app.GridLayout2);
            app.EcualizacinPanel.Title = 'Ecualización';
            app.GridLayout10 = uigridlayout(app.EcualizacinPanel);
            app.EcualizarButton = uibutton(app.GridLayout10, 'push');
            app.EcualizarButton.ButtonPushedFcn = createCallbackFcn(app, @EcualizarButtonPushed, true);
            app.EcualizarButton.Text = 'Ecualizar';
            app.MostrarhistogramasButton = uibutton(app.GridLayout10, 'push');
            app.MostrarhistogramasButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarhistogramasButtonPushed, true);
            app.MostrarhistogramasButton.Text = 'Mostrar histogramas';
            app.InversinPanel = uipanel(app.GridLayout2);
            app.InversinPanel.Title = 'Inversión';
            app.GridLayout11 = uigridlayout(app.InversinPanel);
            app.InvertirbinariaButton = uibutton(app.GridLayout11, 'push');
            app.InvertirbinariaButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirbinariaButtonPushed, true);
            app.InvertirbinariaButton.Text = 'Invertir (binaria)';
            app.InvertirfotogrficaButton = uibutton(app.GridLayout11, 'push');
            app.InvertirfotogrficaButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirfotogrficaButtonPushed, true);
            app.InvertirfotogrficaButton.Text = 'Invertir (fotográfica)';
            app.AdicinysustraccinPanel = uipanel(app.GridLayout2);
            app.AdicinysustraccinPanel.Title = 'Adición y sustracción';
            app.GridLayout12 = uigridlayout(app.AdicinysustraccinPanel);
            app.AdicinButton = uibutton(app.GridLayout12, 'push');
            app.AdicinButton.ButtonPushedFcn = createCallbackFcn(app, @AdicinButtonPushed, true);
            app.AdicinButton.Text = 'Adición';
            app.SustraccinButton = uibutton(app.GridLayout12, 'push');
            app.SustraccinButton.ButtonPushedFcn = createCallbackFcn(app, @SustraccinButtonPushed, true);
            app.SustraccinButton.Text = 'Sustracción';
            app.Requieredeimagen1imagen2Label = uilabel(app.GridLayout12);
            app.Requieredeimagen1imagen2Label.Text = 'Requiere de imagen 1 & imagen 2';
            app.Tab_Collage = uitab(app.MenuTabGroup);
            app.Tab_Collage.Title = 'Collage';
            app.GridLayout21 = uigridlayout(app.Tab_Collage);
            app.OutputPanel = uipanel(app.GridLayout21);
            app.OutputPanel.Title = 'Output';
            app.GridLayout22 = uigridlayout(app.OutputPanel);
            app.GenerarcollageButton = uibutton(app.GridLayout22, 'push');
            app.GenerarcollageButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarcollageButtonPushed, true);
            app.GenerarcollageButton.Text = 'Generar collage';
            app.RedistribuirButton = uibutton(app.GridLayout22, 'push');
            app.RedistribuirButton.Text = 'Redistribuir';
            app.InputPanel = uipanel(app.GridLayout21);
            app.InputPanel.Title = 'Input';
            app.Ingreselasimgenesautilizarenelcollagemax8enelapartadoLabel = uilabel(app.InputPanel);
            app.Ingreselasimgenesautilizarenelcollagemax8enelapartadoLabel.Text = 'Ingrese las imágenes a utilizar en el collage (max y min 9) en las pestañas correspondientes en el apartado de ''Imágenes originales''';
            app.ConfiguracinPanel = uipanel(app.GridLayout21);
            app.ConfiguracinPanel.Title = 'Configuración';
            app.GridLayout23 = uigridlayout(app.ConfiguracinPanel);
            app.WidthEditFieldLabel = uilabel(app.GridLayout23);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.GridLayout23, 'numeric');
            app.WidthEditField.Limits = [1 Inf];
            app.WidthEditField.Value = 1;
            app.HeightEditFieldLabel = uilabel(app.GridLayout23);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.GridLayout23, 'numeric');
            app.HeightEditField.Limits = [1 Inf];
            app.HeightEditField.Value = 1;
            app.EscribaeltamaofinaldelaimagenLabel = uilabel(app.GridLayout23);
            app.EscribaeltamaofinaldelaimagenLabel.Text = 'Escriba el tamaño final de la imagen:';
            app.Tab_RotacionEspejo = uitab(app.MenuTabGroup);
            app.Tab_RotacionEspejo.Title = 'Rotación y espejo';
            app.GridLayout13 = uigridlayout(app.Tab_RotacionEspejo);
            app.RotacinPanel = uipanel(app.GridLayout13);
            app.RotacinPanel.Title = 'Rotación';
            app.GridLayout14 = uigridlayout(app.RotacinPanel);
            app.Rotarimagen45Label = uilabel(app.GridLayout14);
            app.Rotarimagen45Label.Text = 'Rotar imagen 45º';
            app.RotarizquierdaButton = uibutton(app.GridLayout14, 'push');
            app.RotarizquierdaButton.ButtonPushedFcn = createCallbackFcn(app, @RotarizquierdaButtonPushed, true);
            app.RotarizquierdaButton.Text = 'Rotar izquierda';
            app.RotarderechaButton = uibutton(app.GridLayout14, 'push');
            app.RotarderechaButton.ButtonPushedFcn = createCallbackFcn(app, @RotarderechaButtonPushed, true);
            app.RotarderechaButton.Text = 'Rotar derecha';
            app.EspejoPanel = uipanel(app.GridLayout13);
            app.EspejoPanel.Title = 'Espejo';
            app.GridLayout15 = uigridlayout(app.EspejoPanel);
            app.AplicarefectoespejoButton = uibutton(app.GridLayout15, 'push');
            app.AplicarefectoespejoButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarefectoespejoButtonPushed, true);
            app.AplicarefectoespejoButton.Text = 'Aplicar efecto espejo';
            app.Tab_Filtros = uitab(app.MenuTabGroup);
            app.Tab_Filtros.Title = 'Filtros';
            app.GridLayout16 = uigridlayout(app.Tab_Filtros);
            app.FiltrosparabordesPanel = uipanel(app.GridLayout16);
            app.FiltrosparabordesPanel.Title = 'Filtros para bordes';
            app.GridLayout17 = uigridlayout(app.FiltrosparabordesPanel);
            app.PrewittButton = uibutton(app.GridLayout17, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.GridLayout17, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.RobertsButton = uibutton(app.GridLayout17, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
            app.FiltradoespacialPanel = uipanel(app.GridLayout16);
            app.FiltradoespacialPanel.Title = 'Filtrado espacial';
            app.GridLayout18 = uigridlayout(app.FiltradoespacialPanel);
            app.CajaomediaButton = uibutton(app.GridLayout18, 'push');
            app.CajaomediaButton.ButtonPushedFcn = createCallbackFcn(app, @CajaomediaButtonPushed, true);
            app.CajaomediaButton.Text = 'Caja o media';
            app.GaussianoButton = uibutton(app.GridLayout18, 'push');
            app.GaussianoButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianoButtonPushed, true);
            app.GaussianoButton.Text = 'Gaussiano';
            app.Laplaciano4vecinosButton = uibutton(app.GridLayout18, 'push');
            app.Laplaciano4vecinosButton.ButtonPushedFcn = createCallbackFcn(app, @Laplaciano4vecinosButtonPushed, true);
            app.Laplaciano4vecinosButton.Text = 'Laplaciano 4-vecinos';
            app.Laplaciano8vecinosButton = uibutton(app.GridLayout18, 'push');
            app.Laplaciano8vecinosButton.ButtonPushedFcn = createCallbackFcn(app, @Laplaciano8vecinosButtonPushed, true);
            app.Laplaciano8vecinosButton.Text = 'Laplaciano 8-vecinos';
            app.FiltradoespacialordenestadsticoPanel = uipanel(app.GridLayout16);
            app.FiltradoespacialordenestadsticoPanel.Title = 'Filtrado espacial: orden estadístico';
            app.GridLayout19 = uigridlayout(app.FiltradoespacialordenestadsticoPanel);
            app.MedianaButton = uibutton(app.GridLayout19, 'push');
            app.MedianaButton.ButtonPushedFcn = createCallbackFcn(app, @MedianaButtonPushed, true);
            app.MedianaButton.Text = 'Mediana';
            app.ModaButton = uibutton(app.GridLayout19, 'push');
            app.ModaButton.ButtonPushedFcn = createCallbackFcn(app, @ModaButtonPushed, true);
            app.ModaButton.Text = 'Moda';
            app.MximoButton = uibutton(app.GridLayout19, 'push');
            app.MximoButton.ButtonPushedFcn = createCallbackFcn(app, @MximoButtonPushed, true);
            app.MximoButton.Text = 'Máximo';
            app.MnimoButton = uibutton(app.GridLayout19, 'push');
            app.MnimoButton.ButtonPushedFcn = createCallbackFcn(app, @MnimoButtonPushed, true);
            app.MnimoButton.Text = 'Mínimo';
            app.FiltradoespacialordennPanel = uipanel(app.GridLayout16);
            app.FiltradoespacialordennPanel.Title = 'Filtrado espacial: orden n';
            app.GridLayout20 = uigridlayout(app.FiltradoespacialordennPanel);
            app.AplicarfiltroButton = uibutton(app.GridLayout20, 'push');
            app.AplicarfiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarfiltroButtonPushed, true);
            app.AplicarfiltroButton.Text = 'Aplicar filtro';
            app.SeleccioneordenSpinnerLabel = uilabel(app.GridLayout20);
            app.SeleccioneordenSpinnerLabel.Text = 'Seleccione orden';
            app.SeleccioneordenSpinner = uispinner(app.GridLayout20);
            app.SeleccioneordenSpinner.Limits = [1 9];
            app.SeleccioneordenSpinner.Value = 1;
            app.Tab_OperacionesMorfolgicas = uitab(app.MenuTabGroup);
            app.Tab_OperacionesMorfolgicas.Title = 'Operaciones morfológicas';
            app.GridLayout24 = uigridlayout(app.Tab_OperacionesMorfolgicas);
            app.ConsideracinesalingresarelelementoestructurantePanel = uipanel(app.GridLayout24);
            app.ConsideracinesalingresarelelementoestructurantePanel.Title = 'Consideraciónes al ingresar el elemento estructurante';
            app.GridLayout27 = uigridlayout(app.ConsideracinesalingresarelelementoestructurantePanel);
            app.ParaingresarlamatrizdelelementoestructuranteseparelasLabel = uilabel(app.GridLayout27);
            app.ParaingresarlamatrizdelelementoestructuranteseparelasLabel.Text = 'Para ingresar la matriz del elemento estructurante, inicie la definición de la matriz sin "[" y terminela igualmente sin "]". Además, separe los elementos en cada renglón con un espacio y los renglones con un punto y coma (;). Puede dar saltos de línea entre renglones. Ejemplo: 0 1 0; 1 1 1; 0 1 0  ó 1 1 1';
            app.DefinicindelelementoestructurantePanel = uipanel(app.GridLayout24);
            app.DefinicindelelementoestructurantePanel.Title = 'Definición del elemento estructurante';
            app.GridLayout25 = uigridlayout(app.DefinicindelelementoestructurantePanel);
            app.ElementoEstructuranteTextAreaLabel = uilabel(app.GridLayout25);
            app.ElementoEstructuranteTextAreaLabel.Text = 'Elemento Estructurante:';
            app.ElementoEstructuranteTextArea = uitextarea(app.GridLayout25);
            app.ElementoEstructuranteTextArea.Tag = 'elementoEstructuranteMorfologia';
            app.OperacionesmorfolgicasPanel = uipanel(app.GridLayout24);
            app.OperacionesmorfolgicasPanel.Title = 'Operaciones morfológicas';
            app.GridLayout26 = uigridlayout(app.OperacionesmorfolgicasPanel);
            app.DilatacinButton = uibutton(app.GridLayout26, 'push');
            app.DilatacinButton.ButtonPushedFcn = createCallbackFcn(app, @DilatacinButtonPushed, true);
            app.DilatacinButton.Text = 'Dilatación';
            app.ErosinButton = uibutton(app.GridLayout26, 'push');
            app.ErosinButton.ButtonPushedFcn = createCallbackFcn(app, @ErosinButtonPushed, true);
            app.ErosinButton.Text = 'Erosión';
            app.Tab_Segmentacion = uitab(app.MenuTabGroup);
            app.Tab_Segmentacion.Title = 'Segmentación';
            app.GridLayout28 = uigridlayout(app.Tab_Segmentacion);
            app.CrecimientodeRegionesConsideracionesPanel = uipanel(app.GridLayout28);
            app.CrecimientodeRegionesConsideracionesPanel.Title = 'Crecimiento de Regiones (Consideraciones)';
            app.ParaingresarlamatrizdelelementoestructuranteseparelasLabel_2 = uilabel(app.CrecimientodeRegionesConsideracionesPanel);
            app.ParaingresarlamatrizdelelementoestructuranteseparelasLabel_2.Text = 'Considere imagen en escala de grises(recomendado). Debe ingresar una coordenada, un punto en X y otro en Y de acuerdo a su imagen. Considere que para imagenes mayormente grandes el proceso demorará mayor tiempo con respecto a imagenes mas pequeñas.';
            app.CrecimientodeRegionesPanel = uipanel(app.GridLayout28);
            app.CrecimientodeRegionesPanel.Title = 'Crecimiento de Regiones';
            app.YLabel = uilabel(app.CrecimientodeRegionesPanel);
            app.YLabel.Text = 'Y:';
            app.XLabel = uilabel(app.CrecimientodeRegionesPanel);
            app.XLabel.Text = 'X:';
            app.puntoYSeg = uieditfield(app.CrecimientodeRegionesPanel, 'numeric');
            app.puntoXSeg = uieditfield(app.CrecimientodeRegionesPanel, 'numeric');
            app.SegmentarButton = uibutton(app.CrecimientodeRegionesPanel, 'push');
            app.SegmentarButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentarButtonPushed, true);
            app.SegmentarButton.Text = 'Segmentar';
            app.SegmentarsincoordenadasButton = uibutton(app.CrecimientodeRegionesPanel, 'push');
            app.SegmentarsincoordenadasButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentarsincoordenadasButtonPushed, true);
            app.SegmentarsincoordenadasButton.Text = 'Segmentar (sin coordenadas)';
            app.SegmentacionsinCoordenadasparacrecimientoderegionesPanel = uipanel(app.GridLayout28);
            app.SegmentacionsinCoordenadasparacrecimientoderegionesPanel.Title = 'Segmentacion sin Coordenadas (para crecimiento de regiones';
            app.ParaingresarlamatrizdelelementoestructuranteseparelasLabel_3 = uilabel(app.SegmentacionsinCoordenadasparacrecimientoderegionesPanel);
            app.ParaingresarlamatrizdelelementoestructuranteseparelasLabel_3.Text = 'Si va a segmentar una region sin hacer uso de las coordenadas, deje los espacios para ellas en blanco, el programa abrira un selector para que pueda elegir un punto con mayor presicion. recuerde dar doble clic en el punto deseado';
            app.Panel_ImgProcesada = uipanel(app.GridLayout);
            app.Panel_ImgProcesada.Title = 'Imagen procesada';
            app.GridLayout6 = uigridlayout(app.Panel_ImgProcesada);
            app.UIAxes_ImgProcesada = uiaxes(app.GridLayout6);
            title(app.UIAxes_ImgProcesada, 'Imagen procesada')
            xlabel(app.UIAxes_ImgProcesada, 'X')
            ylabel(app.UIAxes_ImgProcesada, 'Y')
            zlabel(app.UIAxes_ImgProcesada, 'Z')
            app.UIAxes_ImgProcesada.Tag = 'axeImagenProcesada';
            app.Panel_ConversionGrayscle = uipanel(app.GridLayout6);
            app.Panel_ConversionGrayscle.Title = 'Convertir imagen';
            app.GridLayout8 = uigridlayout(app.Panel_ConversionGrayscle);
            app.ConvertiraDropDownLabel = uilabel(app.GridLayout8);
            app.ConvertiraDropDownLabel.Text = 'Convertir a :';
            app.DropDown_Convertir = uidropdown(app.GridLayout8);
            app.DropDown_Convertir.Items = {'Seleccionar tipo', 'Binaria', 'Escala de Grises', 'RGB', 'Indexada'};
            app.DropDown_Convertir.ValueChangedFcn = createCallbackFcn(app, @DropDown_ConvertirValueChanged, true);
            app.DropDown_Convertir.Value = 'Seleccionar tipo';
            app.Panel_ArchivoProcesada = uipanel(app.GridLayout6);
            app.Panel_ArchivoProcesada.Title = 'Archivo';
            app.GridLayout9 = uigridlayout(app.Panel_ArchivoProcesada);
            app.Button_Deshacer = uibutton(app.GridLayout9, 'push');
            app.Button_Deshacer.ButtonPushedFcn = createCallbackFcn(app, @Button_DeshacerPushed, true);
            app.Button_Deshacer.Text = 'Deshacer';
            app.Button_Guardar = uibutton(app.GridLayout9, 'push');
            app.Button_Guardar.ButtonPushedFcn = createCallbackFcn(app, @Button_GuardarPushed, true);
            app.Button_Guardar.Text = 'Guardar';
            app.Button_DeshacerTodo = uibutton(app.GridLayout9, 'push');
            app.Button_DeshacerTodo.ButtonPushedFcn = createCallbackFcn(app, @Button_DeshacerTodoPushed, true);
            app.Button_DeshacerTodo.Text = {'Deshacer'; 'todo'};
            app.TabGroup_ImgOriginal = uitabgroup(app.GridLayout);
            app.Tab_ImgOriginal1 = uitab(app.TabGroup_ImgOriginal);
            app.Tab_ImgOriginal1.Title = 'Imagen original 1';
            app.Grid_ImgOriginal = uigridlayout(app.Tab_ImgOriginal1);
            app.UIAxes_ImgOriginal1 = uiaxes(app.Grid_ImgOriginal);
            title(app.UIAxes_ImgOriginal1, 'Imagen original 1')
            xlabel(app.UIAxes_ImgOriginal1, 'X')
            ylabel(app.UIAxes_ImgOriginal1, 'Y')
            zlabel(app.UIAxes_ImgOriginal1, 'Z')
            app.Panel_Archivo = uipanel(app.Grid_ImgOriginal);
            app.Panel_Archivo.Title = 'Archivo';
            app.Grid_Archivo = uigridlayout(app.Panel_Archivo);
            app.Button_QuitarImg1 = uibutton(app.Grid_Archivo, 'push');
            app.Button_QuitarImg1.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg1Pushed, true);
            app.Button_QuitarImg1.Text = 'Quitar';
            app.Button_AbrirImg1 = uibutton(app.Grid_Archivo, 'push');
            app.Button_AbrirImg1.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg1Pushed, true);
            app.Button_AbrirImg1.Text = 'Abrir imagen';
            app.Button_ObtenerInfo = uibutton(app.Grid_Archivo, 'push');
            app.Button_ObtenerInfo.ButtonPushedFcn = createCallbackFcn(app, @Button_ObtenerInfoPushed, true);
            app.Button_ObtenerInfo.Text = 'Obtener información';
            app.Tab_ImgOriginal2a5 = uitab(app.TabGroup_ImgOriginal);
            app.Tab_ImgOriginal2a5.Title = 'Imágenes originales (2-5) ';
            app.Grid_ImgOriginal5 = uigridlayout(app.Tab_ImgOriginal2a5);
            app.UIAxes_ImgOriginal5 = uiaxes(app.Grid_ImgOriginal5);
            title(app.UIAxes_ImgOriginal5, 'Imagen original 5')
            xlabel(app.UIAxes_ImgOriginal5, 'X')
            ylabel(app.UIAxes_ImgOriginal5, 'Y')
            zlabel(app.UIAxes_ImgOriginal5, 'Z')
            app.UIAxes_ImgOriginal4 = uiaxes(app.Grid_ImgOriginal5);
            title(app.UIAxes_ImgOriginal4, 'Imagen original 4')
            xlabel(app.UIAxes_ImgOriginal4, 'X')
            ylabel(app.UIAxes_ImgOriginal4, 'Y')
            zlabel(app.UIAxes_ImgOriginal4, 'Z')
            app.UIAxes_ImgOriginal3 = uiaxes(app.Grid_ImgOriginal5);
            title(app.UIAxes_ImgOriginal3, 'Imagen original 3')
            xlabel(app.UIAxes_ImgOriginal3, 'X')
            ylabel(app.UIAxes_ImgOriginal3, 'Y')
            zlabel(app.UIAxes_ImgOriginal3, 'Z')
            app.UIAxes_ImgOriginal2 = uiaxes(app.Grid_ImgOriginal5);
            title(app.UIAxes_ImgOriginal2, 'Imagen original 2')
            xlabel(app.UIAxes_ImgOriginal2, 'X')
            ylabel(app.UIAxes_ImgOriginal2, 'Y')
            zlabel(app.UIAxes_ImgOriginal2, 'Z')
            app.Button_QuitarImg5 = uibutton(app.Grid_ImgOriginal5, 'push');
            app.Button_QuitarImg5.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg5Pushed, true);
            app.Button_QuitarImg5.Text = 'Quitar';
            app.Button_QuitarImg4 = uibutton(app.Grid_ImgOriginal5, 'push');
            app.Button_QuitarImg4.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg4Pushed, true);
            app.Button_QuitarImg4.Text = 'Quitar';
            app.Button_QuitarImg3 = uibutton(app.Grid_ImgOriginal5, 'push');
            app.Button_QuitarImg3.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg3Pushed, true);
            app.Button_QuitarImg3.Text = 'Quitar';
            app.Button_QuitarImg2 = uibutton(app.Grid_ImgOriginal5, 'push');
            app.Button_QuitarImg2.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg2Pushed, true);
            app.Button_QuitarImg2.Text = 'Quitar';
            app.Button_AbrirImg5 = uibutton(app.Grid_ImgOriginal5, 'push');
            app.Button_AbrirImg5.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg5Pushed, true);
            app.Button_AbrirImg5.Text = 'Abrir imagen 5';
            app.Button_AbrirImg4 = uibutton(app.Grid_ImgOriginal5, 'push');
            app.Button_AbrirImg4.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg4Pushed, true);
            app.Button_AbrirImg4.Text = 'Abrir imagen 4';
            app.Button_AbrirImg3 = uibutton(app.Grid_ImgOriginal5, 'push');
            app.Button_AbrirImg3.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg3Pushed, true);
            app.Button_AbrirImg3.Text = 'Abrir imagen 3';
            app.Button_AbrirImg2 = uibutton(app.Grid_ImgOriginal5, 'push');
            app.Button_AbrirImg2.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg2Pushed, true);
            app.Button_AbrirImg2.Text = 'Abrir imagen 2';
            app.Tab_ImgOriginal6a9 = uitab(app.TabGroup_ImgOriginal);
            app.Tab_ImgOriginal6a9.Title = 'Imágenes originales (6-9)';
            app.Grid_ImgOriginal9 = uigridlayout(app.Tab_ImgOriginal6a9);
            app.UIAxes_ImgOriginal6 = uiaxes(app.Grid_ImgOriginal9);
            title(app.UIAxes_ImgOriginal6, 'Imagen original 6')
            xlabel(app.UIAxes_ImgOriginal6, 'X')
            ylabel(app.UIAxes_ImgOriginal6, 'Y')
            zlabel(app.UIAxes_ImgOriginal6, 'Z')
            app.UIAxes_ImgOriginal7 = uiaxes(app.Grid_ImgOriginal9);
            title(app.UIAxes_ImgOriginal7, 'Imagen original 7')
            xlabel(app.UIAxes_ImgOriginal7, 'X')
            ylabel(app.UIAxes_ImgOriginal7, 'Y')
            zlabel(app.UIAxes_ImgOriginal7, 'Z')
            app.UIAxes_ImgOriginal8 = uiaxes(app.Grid_ImgOriginal9);
            title(app.UIAxes_ImgOriginal8, 'Imagen original 8')
            xlabel(app.UIAxes_ImgOriginal8, 'X')
            ylabel(app.UIAxes_ImgOriginal8, 'Y')
            zlabel(app.UIAxes_ImgOriginal8, 'Z')
            app.UIAxes_ImgOriginal9 = uiaxes(app.Grid_ImgOriginal9);
            title(app.UIAxes_ImgOriginal9, 'Imagen original 9')
            xlabel(app.UIAxes_ImgOriginal9, 'X')
            ylabel(app.UIAxes_ImgOriginal9, 'Y')
            zlabel(app.UIAxes_ImgOriginal9, 'Z')
            app.Button_QuitarImg9 = uibutton(app.Grid_ImgOriginal9, 'push');
            app.Button_QuitarImg9.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg9Pushed, true);
            app.Button_QuitarImg9.Text = 'Quitar';
            app.Button_QuitarImg8 = uibutton(app.Grid_ImgOriginal9, 'push');
            app.Button_QuitarImg8.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg8Pushed, true);
            app.Button_QuitarImg8.Text = 'Quitar';
            app.Button_QuitarImg7 = uibutton(app.Grid_ImgOriginal9, 'push');
            app.Button_QuitarImg7.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg7Pushed, true);
            app.Button_QuitarImg7.Text = 'Quitar';
            app.Button_QuitarImg6 = uibutton(app.Grid_ImgOriginal9, 'push');
            app.Button_QuitarImg6.ButtonPushedFcn = createCallbackFcn(app, @Button_QuitarImg6Pushed, true);
            app.Button_QuitarImg6.Text = 'Quitar';
            app.Button_AbrirImg9 = uibutton(app.Grid_ImgOriginal9, 'push');
            app.Button_AbrirImg9.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg9Pushed, true);
            app.Button_AbrirImg9.Text = 'Abrir imagen 9';
            app.Button_AbrirImg8 = uibutton(app.Grid_ImgOriginal9, 'push');
            app.Button_AbrirImg8.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg8Pushed, true);
            app.Button_AbrirImg8.Text = 'Abrir imagen 8';
            app.Button_AbrirImg7 = uibutton(app.Grid_ImgOriginal9, 'push');
            app.Button_AbrirImg7.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg7Pushed, true);
            app.Button_AbrirImg7.Text = 'Abrir imagen 7';
            app.Button_AbrirImg6 = uibutton(app.Grid_ImgOriginal9, 'push');
            app.Button_AbrirImg6.ButtonPushedFcn = createCallbackFcn(app, @Button_AbrirImg6Pushed, true);
            app.Button_AbrirImg6.Text = 'Abrir imagen 6';
            % Show the figure after all components are created
            app.ProyectoProcesamientodeImgenesDigitalesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplicacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProyectoProcesamientodeImgenesDigitalesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProyectoProcesamientodeImgenesDigitalesUIFigure)
        end
    end
end